classdef SeleccionarFeatures < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        CarregarimagemButton     matlab.ui.control.Button
        ComoutilizarButton       matlab.ui.control.Button
        IniciarButton            matlab.ui.control.Button
        TipodeobjetoListBox_2    matlab.ui.control.ListBox
        TextArea                 matlab.ui.control.TextArea
        SelecionarFeaturesLabel  matlab.ui.control.Label
    end

    %Tornar variáveis acessíveis por todas as funções callback
    properties (Access = public)
        info_imagens;%Guarda as informações de todas as imagens na pasta 'imagens'
        n = 1;%Variável para percorrer a struct com as informações das imagens
        tipo_objeto = 'Carro';%Carro é o objeto padrão
        flag_iniciar = 0;%Sinaliza se o user já inicializou ou não o programa
        flag_carregou_imagem = 0;%Sinaliza se o user já carregou a imagem
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: IniciarButton
        function IniciarButtonPushed(app, event)
        if(~app.flag_iniciar)
            %------------------Criar directorias-------------------%
            mkdir objetos\carro, mkdir objetos\motociclo, mkdir objetos\bicicleta, mkdir objetos\veículoPesado, mkdir objetos\peão, mkdir objetos\árvore
            mkdir objetos\poste, mkdir objetos\sinalização, mkdir objetos\armário, mkdir objetos\outro
            
            mkdir imagensRecortadas
            
            %----Criar struct com as informações de cada imagem----%
            app.info_imagens = dir(fullfile('imagens', '*.png')); %(nome, caminho, data, tamanho, ..., ...)
            app.flag_iniciar = 1;
        else
            msgbox("Já inicializou o programa. Deve clicar em '2 - Carregar imagem'.")
        end
        end

        % Button pushed function: CarregarimagemButton
        function CarregarimagemButtonPushed(app, event)
        if(app.flag_carregou_imagem)
            msgbox("Já existe uma imagem carregada. Só pode ter uma imagem carregada em simultâneo.")
        elseif(~app.flag_iniciar)
            msgbox("Deve inicializar o programa primeiro. Clicar em '1 - Iniciar'.")
        elseif(isempty(app.info_imagens()))
            msgbox("Não existe nenhuma imagem na pasta 'imagens'.")
        elseif(app.n == (size(app.info_imagens, 1) + 1))
            msgbox("Todas as imagens foram recortadas.")            
        else
            app.flag_carregou_imagem = 1;
            %------------Carregar imagem------------%
            caminho_imagem = strcat(app.info_imagens(app.n).folder, '\', app.info_imagens(app.n).name);
            imagem = struct('imagem', [], 'nome', []);
            imagem.imagem = imread(caminho_imagem);
            imagem.nome = app.info_imagens(app.n).name;
            
            %-----------Recortar objetos------------%
            global C type tecla_premida flag_premiu
            h = figure; hold on
            set(h,'WindowButtonDownFcn',@mouseMove)
            set(h,'KeyPressFcn',@teclaPressionada)
             
            imshow(imagem.imagem)
            title(sprintf('Imagem %s', imagem.nome));
            hold on
            type="";
            C =[0, 0];
            pontos = struct('nome', [], 'tipo_objeto', [], 'x', [], 'y', [], 'largura', [], 'altura', []);%guarda os pontos selecionados
            caixa = struct('x', 0, 'y', 0, 'largura', [], 'altura', [], 'flag', 0);%variável aux. para os pontos de cada objeto em cada iteração
            
            while(~strcmp(type,"alt") && ishandle(h))
                pause(0.1)
                
                if (~isequal([caixa.x caixa.y],[C(1,1) C(1,2)]) && ~isequal([C(1,1) C(1,2)], [0 0])...%O ponto não é o mesmo do anterior e não é igual a (0,0)
                    && strcmp(type, 'normal') && caixa.x < C(1,1) && caixa.y < C(1,2)...%O ponto é dado por clique esq. e o ponto é canto inf. dir.
                    && (C(1,1) < size(imagem.imagem, 2)) && (C(1,2) < size(imagem.imagem, 1)))%Não podem ser selecionados pontos fora da imagem
                    
                    plot(C(1,1),C(1,2),'+r','Linewidth',2.0);
                    
                    if (caixa.flag == 0)
                        caixa.x = C(1,1);
                        caixa.y = C(1,2);
                        caixa.flag = 1;
                    elseif (caixa.flag == 1)
                        caixa.largura = abs(caixa.x - C(1,1));
                        caixa.altura = abs(caixa.y - C(1,2));
                        caixa.flag = 2;
                    end
                    
                    if (caixa.flag == 2)
                        line([caixa.x caixa.x+caixa.largura], [caixa.y caixa.y], 'LineWidth', 2);
                        line([caixa.x+caixa.largura caixa.x+caixa.largura], [caixa.y caixa.y+caixa.altura], 'LineWidth', 2);
                        line([caixa.x+caixa.largura caixa.x], [caixa.y+caixa.altura caixa.y+caixa.altura], 'LineWidth', 2);
                        line([caixa.x caixa.x], [caixa.y+caixa.altura caixa.y], 'LineWidth', 2);
                        
                        if (isempty(pontos(end).nome) || strcmp(pontos(end).nome, 'removido'))
                            pontos(end).nome = imagem.nome;
                        else
                            pontos(end+1).nome = imagem.nome;
                        end
                        pontos(end).tipo_objeto = app.tipo_objeto;
                        pontos(end).x = caixa.x;
                        pontos(end).y = caixa.y;
                        pontos(end).largura = caixa.largura;
                        pontos(end).altura = caixa.altura;
                        
                        caixa.x = 0; caixa.y = 0;
                        C =[0, 0];
                        caixa.flag = 0;
                    end
                end

                if (flag_premiu)
                    switch tecla_premida
                        case '1'
                            app.tipo_objeto = 'Carro';
                            app.TextArea.Value = 'Carro';
                        case '2'
                            app.tipo_objeto = 'Motociclo';
                            app.TextArea.Value = 'Motociclo';
                        case '3'
                            app.tipo_objeto = 'Bicicleta';
                            app.TextArea.Value = 'Bicicleta';
                        case '4'
                            app.tipo_objeto = 'Veículo Pesado';
                            app.TextArea.Value = 'Veículo Pesado';
                        case '5'
                            app.tipo_objeto = 'Peão';
                            app.TextArea.Value = 'Peão';
                        case '6'
                            app.tipo_objeto = 'Árvore';
                            app.TextArea.Value = 'Árvore';
                        case '7'
                            app.tipo_objeto = 'Poste';
                            app.TextArea.Value = 'Poste';
                        case '8'
                            app.tipo_objeto = 'Sinalização';
                            app.TextArea.Value = 'Sinalização';
                        case '9'
                            app.tipo_objeto = 'Armário';
                            app.TextArea.Value = 'Armário';
                        case '0'
                            app.tipo_objeto = 'Outro';
                            app.TextArea.Value = 'Outro';                        
                        case 'r'
                            if(~caixa.flag == 1)
                                imshow(imagem.imagem);
                                if(size(pontos(), 2) > 1)
                                    pontos(end) = [];
                                    for i = 1:1:size(pontos, 2)
                                        line([pontos(i).x pontos(i).x+pontos(i).largura], [pontos(i).y pontos(i).y], 'LineWidth', 2);
                                        line([pontos(i).x+pontos(i).largura pontos(i).x+pontos(i).largura], [pontos(i).y pontos(i).y+pontos(i).altura], 'LineWidth', 2);
                                        line([pontos(i).x+pontos(i).largura pontos(i).x], [pontos(i).y+pontos(i).altura pontos(i).y+pontos(i).altura], 'LineWidth', 2);
                                        line([pontos(i).x pontos(i).x], [pontos(i).y+pontos(i).altura pontos(i).y], 'LineWidth', 2);
                                    end
                                else
                                    pontos(end).nome = 'removido';
                                    caixa.flag = 0;
                                end
                            end
                    end
                            
                end
                    flag_premiu = 0;
            end
            
            %Código a seguir ao clique com o BOTÃO DIREITO
            if(~isempty(pontos(1).nome) && ~strcmp(pontos(1).nome, 'removido'))
                %Guarda os objetos
                for i = 1:1:size(pontos, 2)
                	I = imcrop(imagem.imagem, [pontos(i).x, pontos(i).y, pontos(i).largura, pontos(i).altura]);%[xmin ymin width height]
                	[~, nome_ficheiro, ~] = fileparts(pontos(i).nome);
                	caminho_crop = strcat('objetos\', pontos(i).tipo_objeto, '\', nome_ficheiro, '_', int2str(i), '.png');
                	imwrite(I, caminho_crop);
                end
                movefile(caminho_imagem, 'imagensRecortadas')
                
                %Guardar as labels no ficheiro 'labels_coordenadas.mat'
                if (isfile('labels_coordenadas.mat'))
                    fich = matfile('labels_coordenadas.mat','Writable',true);
                    fich.pontos = [fich.pontos, pontos];
                else
                    save 'labels_coordenadas.mat' pontos
                end
                close all
                app.n = app.n + 1;%Avançar para a próxima imagem
                app.flag_carregou_imagem = 0;%Libertar a flag para poder carregar a próxima imagem
            else
                close all
                app.flag_carregou_imagem = 0;
                msgbox("Nenhum objeto selecionado!")
            end
            

        end
        end

        % Button pushed function: ComoutilizarButton
        function ComoutilizarButtonPushed(app, event)
            mh = msgbox([{'-As imagens devem estar guardadas numa directoria com nome "imagens" na mesma directoria deste script.'}...
                         {'-O botão "1 - Iniciar" cria as pastas que serão necessárias durante o programa.'}...
                         {' '}...
                         {'*COMO UTILIZAR*'}...
                         {['1 - Clicar em "1 - Iniciar" e de seguida em "2 - Carregar imagem" ou premir a tecla "q". Após carregar a imagem,'...
                         ' utilizar o botão direito para selecionar os cantos ' ...
                         '(são necessários dois cantos, PRIMEIRO O CANTO SUPERIOR ESQUERDO E DEPOIS O CANTO INFERIOR DIREITO). O programa ' ...
                         'apenas deixa selecionar o canto inferior direito a seguir ao canto superior esquerdo.']}...
                         {['2 - Antes de selecionar o canto inferior direito, escolher o tipo de objeto, clicando na tecla correspondente ' ...
                         '(0-9) ou selecionando na lista. A amarelo está indicado o objeto atual.']}...
                         {'3 - Para eliminar caixas deve-se clicar na tecla "r".'}...
                         {'4 - Após selecionar todos os objetos clicar no botão direito para guardar a imagem.'}...
                         {'5 - Para avançar para a próxima imagem, carregar na tecla "q" ou no botão "2 - Carregar imagem".'}], 'Notas');
            th = findall(mh, 'Type', 'Text');
            th.FontSize = 10;
            deltaWidth = sum(th.Extent([1,3]))-mh.Position(3) + th.Extent(1);
            deltaHeight = sum(th.Extent([2,4]))-mh.Position(4) + 10;
            mh.Position([3,4]) = mh.Position([3,4]) + [deltaWidth, deltaHeight];
        end

        % Value changed function: TipodeobjetoListBox_2
        function TipodeobjetoListBoxValueChanged(app, event)
            value = app.TipodeobjetoListBox_2.Value;
            switch value
                case '1 - Carro'
                    app.tipo_objeto = 'Carro';
                    app.TextArea.Value = 'Carro';
                case '2 - Motociclo'
                    app.tipo_objeto = 'Motociclo';
                    app.TextArea.Value = 'Motociclo';
                case '3 - Bicicleta'
                    app.tipo_objeto = 'Bicicleta';
                    app.TextArea.Value = 'Bicicleta';
                case '4 - Veículo Pesado'
                    app.tipo_objeto = 'Veículo Pesado';
                    app.TextArea.Value = 'Veículo Pesado';
                case '5 - Peão'
                    app.tipo_objeto = 'Peão';    
                    app.TextArea.Value = 'Peão';
                case '6 - Árvore'
                    app.tipo_objeto = 'Árvore';
                    app.TextArea.Value = 'Árvore';
                case '7 - Poste'
                    app.tipo_objeto = 'Poste';
                    app.TextArea.Value = 'Poste';
                case '8 - Sinalização'
                    app.tipo_objeto = 'Sinalização';
                    app.TextArea.Value = 'Sinalização';
                case '9 - Armário'
                    app.tipo_objeto = 'Armário';
                    app.TextArea.Value = 'Armário';
                case '0 - Outro'
                    app.tipo_objeto = 'Outro';
                    app.TextArea.Value = 'Outro';
            end
        end

        % Value changed function: TextArea
        function TextAreaValueChanged(app, event)
           
        end

        % Window key press function: UIFigure
        function UIFigureWindowKeyPress(app, event)
            key = event.Key;
            switch key
                case 'q'
                    CarregarimagemButtonPushed(app, event);
                case '1'
                    app.tipo_objeto = 'Carro';
                    app.TextArea.Value = 'Carro';
                case '2'
                    app.tipo_objeto = 'Motociclo';
                    app.TextArea.Value = 'Motociclo';
                case '3'
                    app.tipo_objeto = 'Bicicleta';
                    app.TextArea.Value = 'Bicicleta';
                case '4'
                    app.tipo_objeto = 'Veículo Pesado';
                    app.TextArea.Value = 'Veículo Pesado';
                case '5'
                    app.tipo_objeto = 'Peão';
                    app.TextArea.Value = 'Peão';
                case '6'
                    app.tipo_objeto = 'Árvore';
                    app.TextArea.Value = 'Árvore';
                case '7'
                    app.tipo_objeto = 'Poste';
                    app.TextArea.Value = 'Poste';
                case '8'
                    app.tipo_objeto = 'Sinalização';
                    app.TextArea.Value = 'Sinalização';
                case '9'
                    app.tipo_objeto = 'Armário';
                    app.TextArea.Value = 'Armário';
                case '0'
                    app.tipo_objeto = 'Outro';
                    app.TextArea.Value = 'Outro';
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.WindowKeyPressFcn = createCallbackFcn(app, @UIFigureWindowKeyPress, true);
            app.CarregarimagemButton = uibutton(app.UIFigure, 'push');
            app.CarregarimagemButton.ButtonPushedFcn = createCallbackFcn(app, @CarregarimagemButtonPushed, true);
            app.CarregarimagemButton.Text = {'2 - Carregar'; 'imagem'};
            app.ComoutilizarButton = uibutton(app.UIFigure, 'push');
            app.ComoutilizarButton.ButtonPushedFcn = createCallbackFcn(app, @ComoutilizarButtonPushed, true);
            app.ComoutilizarButton.Text = 'Como utilizar';
            app.IniciarButton = uibutton(app.UIFigure, 'push');
            app.IniciarButton.ButtonPushedFcn = createCallbackFcn(app, @IniciarButtonPushed, true);
            app.IniciarButton.Text = '1 - Iniciar';
            app.TipodeobjetoListBox_2 = uilistbox(app.UIFigure);
            app.TipodeobjetoListBox_2.Items = {'1 - Carro', '2 - Motociclo', '3 - Bicicleta', '4 - Veículo Pesado', '5 - Peão', '6 - Árvore', '7 - Poste', '8 - Sinalização', '9 - Armário', '0 - Outro'};
            app.TipodeobjetoListBox_2.ValueChangedFcn = createCallbackFcn(app, @TipodeobjetoListBoxValueChanged, true);
            app.TipodeobjetoListBox_2.Value = '1 - Carro';
            app.TextArea = uitextarea(app.UIFigure);
            app.TextArea.ValueChangedFcn = createCallbackFcn(app, @TextAreaValueChanged, true);
            app.TextArea.Editable = 'off';
            app.TextArea.Value = {'Carro'};
            app.SelecionarFeaturesLabel = uilabel(app.UIFigure);
            app.SelecionarFeaturesLabel.Text = 'Selecionar Features';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SeleccionarFeatures

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end